classdef code_correcteur < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CheckconnectionButton      matlab.ui.control.Button
        YourdataEditFieldLabel     matlab.ui.control.Label
        YourdataEditField          matlab.ui.control.EditField
        DataencodedEditFieldLabel  matlab.ui.control.Label
        DataencodedEditField       matlab.ui.control.EditField
        DatasendEditFieldLabel     matlab.ui.control.Label
        DatasendEditField          matlab.ui.control.EditField
        DatareceiveEditFieldLabel  matlab.ui.control.Label
        DatareceiveEditField       matlab.ui.control.EditField
        DatadecodedEditFieldLabel  matlab.ui.control.Label
        DatadecodedEditField       matlab.ui.control.EditField
        DataendEditFieldLabel      matlab.ui.control.Label
        DataendEditField           matlab.ui.control.EditField
        DoexchangeButton           matlab.ui.control.Button
        CorrectorcodeButtonGroup   matlab.ui.container.ButtonGroup
        CRCButton                  matlab.ui.control.RadioButton
        codelinaireButton          matlab.ui.control.RadioButton
        codepolynomialeButton      matlab.ui.control.RadioButton
        ConfigurationsLabel        matlab.ui.control.Label
        ExchangeLabel              matlab.ui.control.Label
        InformationTextArea        matlab.ui.control.TextArea
        SerialportListBoxLabel     matlab.ui.control.Label
        SerialportListBox          matlab.ui.control.ListBox
        RefreshlistSwitchLabel     matlab.ui.control.Label
        RefreshlistSwitch          matlab.ui.control.ToggleSwitch
        CloseconnectionButton      matlab.ui.control.Button
        ChangethedatabitLabel      matlab.ui.control.Label
        ValidateButton             matlab.ui.control.Button
        CreateErrorsLabel          matlab.ui.control.Label
        CreateErrorsEditField      matlab.ui.control.EditField
    end

    
    properties (Access = private)
        serial_com = 0; % for serial communication
        selected_port = 0;
        textEdit_data ="";
    end
    
    methods (Access = private)
        
        function results = finish_exchange(app, char_data_to_send)    
            app.ChangethedatabitLabel.Visible = 'off';
            app.CreateErrorsEditField.Visible = 'off';
            app.CreateErrorsLabel.Visible = 'off'
            app.ValidateButton.Visible = 'off';
        
            % Send to USB cable
            fwrite(app.serial_com,char_data_to_send);
            set(app.DatasendEditField,"Value",char_data_to_send);
            pause(0.001);
            
            % Read data receive
            [data_to_decode_vector, nb_charac] = fread(app.serial_com);
            data_to_decode_str = char([data_to_decode_vector.']);
            set(app.DatareceiveEditField,"Value",data_to_decode_str);
            pause(0.001);
            
            % decode received datas
            decData = crc_function(data_to_decode_str,0);
            set(app.DatadecodedEditField,"Value",decData);
            pause(0.001);
            
            % check error and BER
            % numerr = biterr(data_to_decode_string,decData);
            % set(app.DataendEditField,"Value",numerr);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            all_ports = ["--", seriallist];
            % Changement des valeurs par défaut
            app.SerialportListBox.Items = all_ports;
            set(app.DoexchangeButton, "enable", "off");
            set(app.CloseconnectionButton, "Visible", "off");
        end

        % Button pushed function: CheckconnectionButton
        function CheckconnectionButtonPushed(app, event)
            try
                app.serial_com = serial(app.selected_port);
                fopen(app.serial_com);
                connect_test_response = ["the port is ", get(app.serial_com,"Status")];
                set(app.DoexchangeButton, "enable", "on");
                set(app.CheckconnectionButton, "enable", "off");
                set(app.CloseconnectionButton, "Visible", "on");
            catch ME
                connect_test_response = "Can't connect, try instrfind command to see if port already used";
            end    
              
            app.textEdit_data = [app.textEdit_data, connect_test_response];
            set(app.InformationTextArea,"Value",app.textEdit_data);
        end

        % Value changed function: SerialportListBox
        function SerialportListBoxValueChanged(app, event)
            app.selected_port = get(app.SerialportListBox,"Value");
            app.textEdit_data = [app.textEdit_data, app.selected_port];
            set(app.InformationTextArea,"Value",app.textEdit_data);
        end

        % Button pushed function: DoexchangeButton
        function DoexchangeButtonPushed(app, event)
            data_to_code = get(app.YourdataEditField,"Value");
            if (data_to_code == "")
                app.textEdit_data = [app.textEdit_data, "please enter data noob"];
                set(app.InformationTextArea,"Value",app.textEdit_data);
                pause(0.001);
            else
            
            % Call external script fonction of the corretor Code Selection
            % chosen
            code_correcteur_selectionne = get(get(app.CorrectorcodeButtonGroup, "SelectedObject"),"Text");
            app.textEdit_data = [app.textEdit_data, "code selected : ", code_correcteur_selectionne];
            set(app.InformationTextArea,"Value",app.textEdit_data);
                
            if (code_correcteur_selectionne == "CRC")
                data_encoded = crc_function(data_to_code,1);
            elseif (code_correcteur_selectionne == "code linéaire")
                data_encoded = lineaire_function();
            elseif (code_correcteur_selectionne == "code polynomiale")
                data_encoded = polynomiale_function();
            end
            
            % Display coded data after coding
            set(app.DataencodedEditField,"Value",data_encoded);
            pause(0.001);
                       
            % Go to binary to change some bits
            x= reshape(dec2bin(data_encoded, 8)', 1, [])  - '0';
            
            % Open the change bit parameters
            app.ChangethedatabitLabel.Visible = 'on';
            app.CreateErrorsEditField.Visible = 'on';
            app.CreateErrorsLabel.Visible = 'on'
            app.ValidateButton.Visible = 'on';
            
            % Display the bits in editfield and call callback to print
            % value in label
            set(app.CreateErrorsEditField, "Value", num2str(x));
            CreateErrorsEditFieldValueChanged(app, event);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try 
                CloseconnectionButtonPushed(app, event);
                % fclose(app.serial_com);
            catch ME
                CloseconnectionButtonPushed(app, event)
                % fclose(app.serial_com);
            end
            delete(app);
        end

        % Value changed function: RefreshlistSwitch
        function RefreshlistSwitchValueChanged(app, event)
            value = app.RefreshlistSwitch.Value;
            all_ports = ["--", seriallist];
            % Changement des valeurs par défaut
            app.SerialportListBox.Items = all_ports;
        end

        % Button pushed function: CloseconnectionButton
        function CloseconnectionButtonPushed(app, event)
            try 
                fclose(app.serial_com);
                
                set(app.DoexchangeButton, "enable", "off");
                set(app.CloseconnectionButton, "Visible", "off");
                close_test_response = ["the port is ", get(app.serial_com,"Status")];
                set(app.CheckconnectionButton, "enable", "on");
            catch ME
                close_test_response = ["Can't close port ! "];
            end
            app.textEdit_data = [app.textEdit_data, close_test_response];
            set(app.InformationTextArea,"Value",app.textEdit_data);
        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
            finish_exchange(app,get(app.CreateErrorsLabel,"Text"));
        end

        % Value changed function: CreateErrorsEditField
        function CreateErrorsEditFieldValueChanged(app, event)
           value = app.CreateErrorsEditField.Value;
           my_data_field = app.CreateErrorsEditField.Value;
           
           % Get the string instead of bit
           my_data_field_num = str2num(my_data_field);
           
            % Reconvert to char
            char_data='';
            for j = (0:length(my_data_field_num)/8-1)
                char_data = [char_data,sum(my_data_field_num(1+(j*8):8+(j*8)).*2.^[7:-1:0])];
            end
            % Display char result to labem
            app.CreateErrorsLabel.Text = char_data;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CheckconnectionButton = uibutton(app.UIFigure, 'push');
            app.CheckconnectionButton.ButtonPushedFcn = createCallbackFcn(app, @CheckconnectionButtonPushed, true);
            app.CheckconnectionButton.Text = 'Check connection';
            app.YourdataEditFieldLabel = uilabel(app.UIFigure);
            app.YourdataEditFieldLabel.Text = 'Your data';
            app.YourdataEditField = uieditfield(app.UIFigure, 'text');
            app.DataencodedEditFieldLabel = uilabel(app.UIFigure);
            app.DataencodedEditFieldLabel.Text = 'Data encoded';
            app.DataencodedEditField = uieditfield(app.UIFigure, 'text');
            app.DatasendEditFieldLabel = uilabel(app.UIFigure);
            app.DatasendEditFieldLabel.Text = 'Data send';
            app.DatasendEditField = uieditfield(app.UIFigure, 'text');
            app.DatareceiveEditFieldLabel = uilabel(app.UIFigure);
            app.DatareceiveEditFieldLabel.Text = 'Data receive';
            app.DatareceiveEditField = uieditfield(app.UIFigure, 'text');
            app.DatadecodedEditFieldLabel = uilabel(app.UIFigure);
            app.DatadecodedEditFieldLabel.Text = 'Data decoded';
            app.DatadecodedEditField = uieditfield(app.UIFigure, 'text');
            app.DataendEditFieldLabel = uilabel(app.UIFigure);
            app.DataendEditFieldLabel.Text = 'Data end';
            app.DataendEditField = uieditfield(app.UIFigure, 'text');
            app.DoexchangeButton = uibutton(app.UIFigure, 'push');
            app.DoexchangeButton.ButtonPushedFcn = createCallbackFcn(app, @DoexchangeButtonPushed, true);
            app.DoexchangeButton.Text = 'Do exchange';
            app.CorrectorcodeButtonGroup = uibuttongroup(app.UIFigure);
            app.CorrectorcodeButtonGroup.Title = 'Corrector code';
            app.CRCButton = uiradiobutton(app.CorrectorcodeButtonGroup);
            app.CRCButton.Text = 'CRC';
            app.CRCButton.Value = true;
            app.codelinaireButton = uiradiobutton(app.CorrectorcodeButtonGroup);
            app.codelinaireButton.Text = 'code linéaire';
            app.codepolynomialeButton = uiradiobutton(app.CorrectorcodeButtonGroup);
            app.codepolynomialeButton.Text = 'code polynomiale';
            app.ConfigurationsLabel = uilabel(app.UIFigure);
            app.ConfigurationsLabel.Text = 'Configurations';
            app.ExchangeLabel = uilabel(app.UIFigure);
            app.ExchangeLabel.Text = 'Exchange';
            app.InformationTextArea = uitextarea(app.UIFigure);
            app.SerialportListBoxLabel = uilabel(app.UIFigure);
            app.SerialportListBoxLabel.Text = 'Serial port';
            app.SerialportListBox = uilistbox(app.UIFigure);
            app.SerialportListBox.ValueChangedFcn = createCallbackFcn(app, @SerialportListBoxValueChanged, true);
            app.RefreshlistSwitchLabel = uilabel(app.UIFigure);
            app.RefreshlistSwitchLabel.Text = 'Refresh list';
            app.RefreshlistSwitch = uiswitch(app.UIFigure, 'toggle');
            app.RefreshlistSwitch.ValueChangedFcn = createCallbackFcn(app, @RefreshlistSwitchValueChanged, true);
            app.CloseconnectionButton = uibutton(app.UIFigure, 'push');
            app.CloseconnectionButton.ButtonPushedFcn = createCallbackFcn(app, @CloseconnectionButtonPushed, true);
            app.CloseconnectionButton.Text = 'Close connection';
            app.ChangethedatabitLabel = uilabel(app.UIFigure);
            app.ChangethedatabitLabel.Visible = 'off';
            app.ChangethedatabitLabel.Text = 'Change the data bit';
            app.ValidateButton = uibutton(app.UIFigure, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Visible = 'off';
            app.ValidateButton.Text = 'Validate';
            app.CreateErrorsLabel = uilabel(app.UIFigure);
            app.CreateErrorsLabel.Visible = 'off';
            app.CreateErrorsEditField = uieditfield(app.UIFigure, 'text');
            app.CreateErrorsEditField.ValueChangedFcn = createCallbackFcn(app, @CreateErrorsEditFieldValueChanged, true);
            app.CreateErrorsEditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = code_correcteur

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end